classdef ISETBioCSFGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainView                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        radialeccentricitydegsSliderLabel  matlab.ui.control.Label
        radialeccentricitydegsSlider  matlab.ui.control.Slider
        sizedegsSliderLabel           matlab.ui.control.Label
        sizedegsSlider                matlab.ui.control.Slider
        polareccentricitydegsSlider   matlab.ui.control.Knob
        visualFieldView               matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        mosaicView                    matlab.ui.control.UIAxes
        psfView                       matlab.ui.control.UIAxes
        csfView                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        radialEccentricityDegs = 0;              % Continuously-updated radial eccentricity
        polarEccentricityDegs = 0;               % Continuously-updated polar eccentricity
        mosaicSizeDegs = 0;                      % Size of the cone mosaic, in degrees
        mosaicEccentricityDegs;                  % Eccentricity of the cone mosaic, computed
        components = struct();                   % Struct containing simulation components, eg. coneMosaic
        mosaicOnVisualFieldPlotHandle;           % The mosaic on visual field plot handle
        maxEcc = 25.0;
    end
    
     
    methods (Access = private)
        
        % Method called from the startup method as soon as the GUI is
        % presented. Used to initialize the simulation params
        function initializeSimulationParams(app)
            app.radialeccentricitydegsSlider.Value
            app.readRadialEccentricityDegsSlider(app.radialeccentricitydegsSlider.Value); 
            app.readPolarEccentricityDegsSlider(app.polareccentricitydegsSlider.Value);          
            app.readSizeDegsSlider(app.sizedegsSlider.Value);
        end
        
        % Method called from the startup method as soon as the GUI is
        % presented. Used to set slider values etc.
        function initializeGUIcomponents(app)
            app.updateRadialEccentricitySliderText();
            app.updateMosaicSizeSliderText();
            app.initializePolarEccentricitySliderTicks();
            drawnow;
        end
        
        % Method called from the startup method as soon as the GUI is
        % presented. Used to initialize all views.
        function initializeViews(app)
            app.initializeVisualFieldView();
            app.initializeMosaicView();
            app.initializePSFView();
            app.initializeCSFView();
            app.updateVisualFieldView();
            drawnow;
        end
        
        function initializeMosaicView(app)
            box(app.mosaicView, 'on');
            set(app.mosaicView, 'XLim', [-0.1 0.1], 'YLim', [-0.1 0.1], ...
                'XTick', [-0.1 0 0.1], 'YTick', [-0.1 0 0.1])
        end
        
        function initializePSFView(app)
            spatialSupportArcMinRange = 5;
            set(app.psfView, 'XLim', spatialSupportArcMinRange*[-0.5 0.5], 'YLim', spatialSupportArcMinRange*[-0.5 0.5], ...
                'XTick', -10:1:10, 'YTick', -10:1:10, 'FontSize', 14);
            box(app.psfView, 'on');
            grid(app.psfView, 'on');
            xlabel(app.psfView, 'arc min.');
            ylabel(app.psfView, 'arc min.');
        end
        
        function initializeCSFView(app)
            set(app.csfView, 'XLim', [0.3 50], 'YLim', [1 1000], ...
                'XTick', [0.3 1 3 10 30], 'YTick', [1 3 10 30 100 300 1000], ...
                'XLim', [0.2 60], 'YLim', [1 1000], 'XScale', 'log', 'YScale', 'log', 'FontSize', 14);
            box(app.csfView, 'on');
            grid(app.csfView, 'on');
            xlabel(app.csfView, 'spatial frequency (c/deg)');
            ylabel(app.csfView, 'contrast sensitivity');
        end
        
        function initializeVisualFieldView(app)
            plot(app.visualFieldView, [-32 32], [0 0], 'k-', 'LineWidth', 1.5, 'Color', [0.5 0.5 0.5]);
            hold(app.visualFieldView, 'on');
            plot(app.visualFieldView, [0 0], [-32 32], 'k-', 'LineWidth', 1.5, 'Color', [0.5 0.5 0.5]);
            for radius = [0.15 0.3 0.6 1.2 2.5 5 10 20]
                logRadius = 25*(1+log10(radius))/(1+log10(app.maxEcc));
                plot(app.visualFieldView, logRadius*cosd(0:5:360), logRadius*sind(0:5:360), 'k-', 'Color', [0.5 0.5 0.5], 'LineWidth', 1.0);
            end
            app.mosaicOnVisualFieldPlotHandle = scatter(app.visualFieldView, 0, 0, 14*14, 'ro', 'MarkerFaceColor', [1 0.5 0.5], 'MarkerFaceAlpha', 0.5);
            hold(app.visualFieldView, 'off');
            set(app.visualFieldView, 'XTick', 0, 'YTick', 0, 'XLim', app.maxEcc*[-1 1], 'YLim', app.maxEcc*[-1 1], 'FontSize', 14);
            set(app.visualFieldView, 'Color', 'none', 'XColor', 'none', 'YColor', 'none');
            set(app.visualFieldView, 'XTickLabel', '0');
            set(app.visualFieldView, 'YTickLabel', '0');
            box(app.visualFieldView, 'off');
            xlabel(app.visualFieldView, 'x (degs)');
            ylabel(app.visualFieldView, 'y (degs)');
        end
        
        function initializeSimulationComponents(app)
            % Start the parpool
            dialogBox = uiprogressdlg(app.mainView,'Title','Please Wait',...
                'Message','Starting the parallel pool');
            dialogBox.Value = 0.2; 
            pause(.01)
            poolobj = gcp('nocreate'); 
            if isempty(poolobj)
                parpool('local')
            end
            
            % Regenerate the cone mosaic
            app.regenerateMosaic(dialogBox, 0.5, 1.0);
            
            % Close dialog box
            close(dialogBox);
        end
        
        % Method to regenerate the cone mosaic and update the current
        % progress bar
        function regenerateMosaic(app, dialogBox, currentProgressValue, nextProgressValue)
            
            if (currentProgressValue == 0) 
                dialogBox = uiprogressdlg(app.mainView,'Title','Please Wait',...
                'Message','');
                pause(.01)
            end
            
            deltaProgressValue = (nextProgressValue-currentProgressValue)/2;
            
            % Generate new cone mosaic
            dialogBox.Value = currentProgressValue+deltaProgressValue; 
            dialogBox.Message = 'Generating cone mosaic';
            pause(.01)
            app.components.coneMosaic = cMosaic(...
                'sizeDegs', app.mosaicSizeDegs*[1 1], ... 
                'eccentricityDegs', app.mosaicEccentricityDegs ...  
            );
            
            % Visualize the generated cone mosaic
            dialogBox.Value = currentProgressValue+2*deltaProgressValue; 
            dialogBox.Message = 'Rendering cone mosaic';
            pause(.01);
            
            app.components.coneMosaic.visualize(...
                'figureHandle', app.mainView, ...
                'axesHandle', app.mosaicView, ...
                'crossHairsOnFovea', true, ...
                'fontSize', 14);
            if (currentProgressValue == 0)
                % Close dialog box
                close(dialogBox);
            end
            
        end
        
        function readRadialEccentricityDegsSlider(app, value)
            actualValues = round(logspace(log10(0.15),log10(40), 9)*100)/100;
            actualValues = [0 actualValues(1:end-1)];
            app.radialEccentricityDegs = interp1(0:(numel(actualValues)-1), actualValues, value);
            app.mosaicEccentricityDegs(1) = app.radialEccentricityDegs * cosd(app.polarEccentricityDegs);
            app.mosaicEccentricityDegs(2) = app.radialEccentricityDegs * sind(app.polarEccentricityDegs);
        end
        
        function readPolarEccentricityDegsSlider(app, value)
            app.polarEccentricityDegs =-value;
            app.mosaicEccentricityDegs(1) = app.radialEccentricityDegs * cosd(app.polarEccentricityDegs);
            app.mosaicEccentricityDegs(2) = app.radialEccentricityDegs * sind(app.polarEccentricityDegs);
        end
        
        function readSizeDegsSlider(app, value)
            actualValues = [0.07 0.15 0.3 0.6 1.2 2.5 5 10];
            app.mosaicSizeDegs  = interp1(1:numel(actualValues), actualValues, value);
        end
        
        function updateRadialEccentricitySliderText(app)
            radialEccentricity = sqrt(sum(app.mosaicEccentricityDegs.^2,2));
            if (app.mosaicEccentricityDegs < 1.0)
                 app.radialeccentricitydegsSliderLabel.Text = sprintf('radial eccentricity (degs): %2.2f', radialEccentricity );
            elseif (app.mosaicEccentricityDegs < 10.0)
                 app.radialeccentricitydegsSliderLabel.Text = sprintf('radial eccentricity (degs): %2.1f', radialEccentricity);
            else
                 app.radialeccentricitydegsSliderLabel.Text = sprintf('radial eccentricity (degs): %2.0f', radialEccentricity);
            end
        end
        
        function updateMosaicSizeSliderText(app)
            if (app.mosaicSizeDegs < 1.0)
                 app.sizedegsSliderLabel.Text =  sprintf('field of view (degs): %2.2f', app.mosaicSizeDegs);
            elseif (app.mosaicSizeDegs < 10.0)
                 app.sizedegsSliderLabel.Text = sprintf('field of view (degs): %2.1f', app.mosaicSizeDegs);
            else
                 app.sizedegsSliderLabel.Text = sprintf('field of view(degs): %2.0f', app.mosaicSizeDegs);
            end
        end
        
        function initializePolarEccentricitySliderTicks(app)
            app.polareccentricitydegsSlider.MajorTicks = 0:45:360;
            app.polareccentricitydegsSlider.MajorTickLabels = {'360', '315', '270', '225', '180',  '135',  '90',   '45', '0'};
            app.polareccentricitydegsSlider.MinorTicks = [];
        end
        
        function updateVisualFieldView(app)
            app.mosaicEccentricityDegs = app.radialEccentricityDegs * [cosd(app.polarEccentricityDegs) sind(app.polarEccentricityDegs)];
            if (log10(app.radialEccentricityDegs)>-1)
                logRadius = 25*(1+log10(app.radialEccentricityDegs))/(1+log10(app.maxEcc));
            else
                logRadius = 0;
            end
            
            set(app.mosaicOnVisualFieldPlotHandle, ...
                'XData',logRadius*cosd(app.polarEccentricityDegs), ...
                'YData',logRadius*sind(app.polarEccentricityDegs));
            app.updateRadialEccentricitySliderText();
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initializeSimulationParams();
            app.initializeViews();
            app.initializeGUIcomponents();
            app.initializeSimulationComponents();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.mainView.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {889, 889};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {519, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: radialeccentricitydegsSlider
        function radialeccentricitydegsSliderUpdated(app, event)
            app.readRadialEccentricityDegsSlider(app.radialeccentricitydegsSlider.Value);
            app.updateRadialEccentricitySliderText();
            regenerateMosaic(app, [], 0,1);
        end

        % Value changed function: sizedegsSlider
        function sideDegsSliderUpdated(app, event)
            app.readSizeDegsSlider(app.sizedegsSlider.Value);
            app.updateMosaicSizeSliderText();
            regenerateMosaic(app, [], 0, 1);
        end

        % Value changing function: radialeccentricitydegsSlider
        function radialeccentricitySliderContinuousUpdate(app, event)
            app.readRadialEccentricityDegsSlider(event.Value);
            app.updateVisualFieldView();
        end

        % Value changed function: polareccentricitydegsSlider
        function polareccentricitydegsSliderUpdated(app, event)
            app.readPolarEccentricityDegsSlider(app.polareccentricitydegsSlider.Value);
            regenerateMosaic(app, [], 0,1);
        end

        % Value changing function: polareccentricitydegsSlider
        function polareccentricitySliderContinuousUpdate(app, event)
            app.readPolarEccentricityDegsSlider(event.Value);
            app.updateVisualFieldView();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainView = uifigure('Visible', 'off');
            app.mainView.Name = 'Interactive ISETBio CSF Generator, Written by N.P. Cottaris, ISETBio Team, 2021';
            app.mainView.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.mainView);
            app.LeftPanel = uipanel(app.GridLayout);
            app.radialeccentricitydegsSliderLabel = uilabel(app.LeftPanel);
            app.radialeccentricitydegsSliderLabel.Tag = 'eccntricitySliderText';
            app.radialeccentricitydegsSliderLabel.Text = 'radial eccentricity (degs)';
            app.radialeccentricitydegsSlider = uislider(app.LeftPanel);
            app.radialeccentricitydegsSlider.Limits = [0 8];
            app.radialeccentricitydegsSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.radialeccentricitydegsSlider.MajorTickLabels = {'0', '.15', '.3', '.6', '1.2', '2.5', '5', '10', '20'};
            app.radialeccentricitydegsSlider.ValueChangedFcn = createCallbackFcn(app, @radialeccentricitydegsSliderUpdated, true);
            app.radialeccentricitydegsSlider.ValueChangingFcn = createCallbackFcn(app, @radialeccentricitySliderContinuousUpdate, true);
            app.radialeccentricitydegsSlider.MinorTicks = [];
            app.radialeccentricitydegsSlider.Value = 1;
            app.sizedegsSliderLabel = uilabel(app.LeftPanel);
            app.sizedegsSliderLabel.Text = 'size (degs)';
            app.sizedegsSlider = uislider(app.LeftPanel);
            app.sizedegsSlider.Limits = [1 8];
            app.sizedegsSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.sizedegsSlider.MajorTickLabels = {'0.07 ', '0.15 ', '0.3 ', '0.6 ', '1.2', ' 2.5', ' 5', '10'};
            app.sizedegsSlider.ValueChangedFcn = createCallbackFcn(app, @sideDegsSliderUpdated, true);
            app.sizedegsSlider.MinorTicks = [];
            app.sizedegsSlider.Value = 4;
            app.polareccentricitydegsSlider = uiknob(app.LeftPanel, 'continuous');
            app.polareccentricitydegsSlider.Limits = [0 360];
            app.polareccentricitydegsSlider.MajorTicks = [0 90 180 270 360];
            app.polareccentricitydegsSlider.MajorTickLabels = {'0', '90 ', '180 ', '270 ', '360', ''};
            app.polareccentricitydegsSlider.ValueChangedFcn = createCallbackFcn(app, @polareccentricitydegsSliderUpdated, true);
            app.polareccentricitydegsSlider.ValueChangingFcn = createCallbackFcn(app, @polareccentricitySliderContinuousUpdate, true);
            app.polareccentricitydegsSlider.MinorTicks = [];
            app.polareccentricitydegsSlider.Value = 360;
            app.visualFieldView = uiaxes(app.LeftPanel);
            title(app.visualFieldView, 'visual field')
            xlabel(app.visualFieldView, 'X')
            ylabel(app.visualFieldView, 'Y')
            zlabel(app.visualFieldView, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.mosaicView = uiaxes(app.RightPanel);
            title(app.mosaicView, 'mosaic activation')
            xlabel(app.mosaicView, 'X')
            ylabel(app.mosaicView, 'Y')
            zlabel(app.mosaicView, 'Z')
            app.psfView = uiaxes(app.RightPanel);
            title(app.psfView, 'PSF')
            xlabel(app.psfView, 'X')
            ylabel(app.psfView, 'Y')
            zlabel(app.psfView, 'Z')
            app.csfView = uiaxes(app.RightPanel);
            title(app.csfView, 'CSF')
            xlabel(app.csfView, 'X')
            ylabel(app.csfView, 'Y')
            zlabel(app.csfView, 'Z')
            % Show the figure after all components are created
            app.mainView.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISETBioCSFGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainView)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainView)
        end
    end
end