classdef ISETBioCSFGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainView                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        fixationalEyeMovementsTab     matlab.ui.container.Tab
        opticsTab                     matlab.ui.container.Tab
        wavelengthnmSliderLabel       matlab.ui.control.Label
        opticsVisualizedWavelengthSlider  matlab.ui.control.Slider
        opticsView                    matlab.ui.control.UIAxes
        coneMosaicTab                 matlab.ui.container.Tab
        Label                         matlab.ui.control.Label
        coneMosaicViewModeKnob        matlab.ui.control.DiscreteKnob
        coneMosaicView                matlab.ui.control.UIAxes
        mRGCmosaicTab                 matlab.ui.container.Tab
        pRGCmosaicTab                 matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        visualFieldTab                matlab.ui.container.Tab
        visualFieldEyeSwitch          matlab.ui.control.Switch
        radialeccentricitydegsLabel   matlab.ui.control.Label
        visualFieldRadialEccentricitySlider  matlab.ui.control.Slider
        polareccentricitydegsLabel    matlab.ui.control.Label
        visualFieldPolarEccentricityKnob  matlab.ui.control.Knob
        fieldofviewdegsLabel          matlab.ui.control.Label
        visualFieldFieldOfViewSlider  matlab.ui.control.Slider
        magnificationfactorumdegEditFieldLabel  matlab.ui.control.Label
        visualFieldMagnificationFactorEditField  matlab.ui.control.NumericEditField
        visualFieldView               matlab.ui.control.UIAxes
        stimulusTab                   matlab.ui.container.Tab
        performanceassessmentTab      matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        leftStatusField               matlab.ui.control.EditField
        Panel_2                       matlab.ui.container.Panel
        rightStatusField              matlab.ui.control.EditField
    end

    
    properties (Access = public)
        % Params for various processing pipeline components
        visualFieldParams               % struct with all visual field params
        coneMosaicParams                % struct with all cone mosaic params
        opticsParams                    % struct with all optics params
        
        % Components of the processing pipeline
        components                      % struct containing the various processing objects
        
        % handles to various plots
        odOnVisualFieldPlotHandle       % handle to the optic disk plot
        mosaicOnVisualFieldPlotHandle   % handle to the mosaic outline plot
        
        psfDensityPlotHandle            % handle to the density plot of the PSF
        coneOutlineOnPSFPlotHandles     % array of handles to the cones of PSF plot
        centralConeOutlinesArcMin       % x,y coords of central cones in current mosaic
        centralConeOutlinesNum = 7+13;
        
        % XYlimits for various zoomable plots
        opticsViewLimsArcMin = [];
        
    end
    
    methods (Access = public)
        
        % Handler function for optics zoom event
        function handleOpticsZoomEvent(app,src,evnt)
            switch src.Name
                case 'XLim'
                    app.opticsViewLimsArcMin = evnt.AffectedObject.XLim;
        
                case 'YLim'
                    app.opticsViewLimsArcMin = evnt.AffectedObject.YLim;
            end
            
            if (max(app.opticsViewLimsArcMin) < 2)
                set(app.opticsView, 'XTick', -2:0.5:2);
            elseif (max(app.opticsViewLimsArcMin) < 5)
                set(app.opticsView, 'XTick', -5:1:5);
            elseif (max(app.opticsViewLimsArcMin) < 10)
                set(app.opticsView, 'XTick', -10:2:10);
            elseif (max(app.opticsViewLimsArcMin) < 20)
                set(app.opticsView, 'XTick', -20:4:20);
            else
                set(app.opticsView, 'XTick', -30:5:30);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CSFGeneratorApp.initialize.simulationParams(app);
            CSFGeneratorApp.initialize.guiComponents(app);
            CSFGeneratorApp.initialize.allViews(app);
            CSFGeneratorApp.render.visualFieldView(app, 'update');
            CSFGeneratorApp.generate.processingPipeline(app);
        end

        % Value changed function: visualFieldEyeSwitch
        function visualFieldEyeSwitchValueChanged(app, event)
            value = app.visualFieldEyeSwitch.Value;
            CSFGeneratorApp.decode.eyeSwitch(app, 'sliderToValue', value);
            CSFGeneratorApp.generate.processingPipeline(app);
        end

        % Value changed function: visualFieldPolarEccentricityKnob
        function visualFieldPolarEccentricityKnobValueChanged(app, event)
            value = app.visualFieldPolarEccentricityKnob.Value;
            CSFGeneratorApp.decode.polarEccentricitySlider(app, 'sliderToValue', value);
            CSFGeneratorApp.render.visualFieldView(app, 'update');
            CSFGeneratorApp.generate.processingPipeline(app);
        end

        % Value changing function: visualFieldPolarEccentricityKnob
        function visualFieldPolarEccentricityKnobValueChanging(app, event)
            changingValue = event.Value;
            CSFGeneratorApp.decode.polarEccentricitySlider(app, 'sliderToValue', changingValue);
            CSFGeneratorApp.render.visualFieldView(app, 'update');
        end

        % Value changed function: 
        % visualFieldRadialEccentricitySlider
        function visualFieldRadialEccentricitySliderValueChanged(app, event)
            value = app.visualFieldRadialEccentricitySlider.Value;
            CSFGeneratorApp.decode.radialEccentricitySlider(app, 'sliderToValue', value);
            CSFGeneratorApp.render.visualFieldView(app, 'update');
            CSFGeneratorApp.generate.processingPipeline(app);
        end

        % Value changing function: 
        % visualFieldRadialEccentricitySlider
        function visualFieldRadialEccentricitySliderValueChanging(app, event)
            changingValue = event.Value;
            CSFGeneratorApp.decode.radialEccentricitySlider(app, 'sliderToValue', changingValue);
            CSFGeneratorApp.render.visualFieldView(app, 'update');
        end

        % Value changed function: visualFieldFieldOfViewSlider
        function visualFieldFieldOfViewSliderValueChanged(app, event)
            value = app.visualFieldFieldOfViewSlider.Value;
            CSFGeneratorApp.decode.fieldOfViewSlider(app, 'sliderToValue', value);
            CSFGeneratorApp.render.visualFieldView(app, 'update');
            CSFGeneratorApp.generate.processingPipeline(app);
        end

        % Value changing function: visualFieldFieldOfViewSlider
        function visualFieldFieldOfViewSliderValueChanging(app, event)
            changingValue = event.Value;
            CSFGeneratorApp.decode.fieldOfViewSlider(app, 'sliderToValue', changingValue);
            CSFGeneratorApp.render.visualFieldView(app, 'update');
        end

        % Value changed function: opticsVisualizedWavelengthSlider
        function opticsVisualizedWavelengthSliderChanged(app, event)
            value = app.opticsVisualizedWavelengthSlider.Value;
            CSFGeneratorApp.decode.opticsWavelengthSlider(app, 'sliderToValue', value);
            CSFGeneratorApp.render.opticsView(app, 'update');
        end

        % Value changing function: opticsVisualizedWavelengthSlider
        function opticsWavelengthSliderChanging(app, event)
            changingValue = event.Value;
            CSFGeneratorApp.decode.opticsWavelengthSlider(app, 'sliderToValue', changingValue);
            CSFGeneratorApp.render.opticsView(app, 'update');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainView = uifigure('Visible', 'off');
            app.mainView.Name = 'ISETBio CSF Generator, Developed by N.P. Cottaris, ISETBio Team, 2021';
            app.GridLayout = uigridlayout(app.mainView);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.fixationalEyeMovementsTab = uitab(app.TabGroup);
            app.fixationalEyeMovementsTab.Title = 'fixational eye movements';
            app.opticsTab = uitab(app.TabGroup);
            app.opticsTab.Title = 'optics';
            app.wavelengthnmSliderLabel = uilabel(app.opticsTab);
            app.wavelengthnmSliderLabel.Text = 'wavelength (nm)';
            app.opticsVisualizedWavelengthSlider = uislider(app.opticsTab);
            app.opticsVisualizedWavelengthSlider.Limits = [400 750];
            app.opticsVisualizedWavelengthSlider.ValueChangedFcn = createCallbackFcn(app, @opticsVisualizedWavelengthSliderChanged, true);
            app.opticsVisualizedWavelengthSlider.ValueChangingFcn = createCallbackFcn(app, @opticsWavelengthSliderChanging, true);
            app.opticsVisualizedWavelengthSlider.Value = 550;
            app.opticsView = uiaxes(app.opticsTab);
            xlabel(app.opticsView, 'X')
            zlabel(app.opticsView, 'Z')
            app.coneMosaicTab = uitab(app.TabGroup);
            app.coneMosaicTab.Title = 'cone mosaic ';
            app.Label = uilabel(app.coneMosaicTab);
            app.Label.Text = '';
            app.coneMosaicViewModeKnob = uiknob(app.coneMosaicTab, 'discrete');
            app.coneMosaicViewModeKnob.Items = {'cone types', 'cones&retinal image', 'activation', 'modulation', 'residual'};
            app.coneMosaicViewModeKnob.Value = 'cones&retinal image';
            app.coneMosaicView = uiaxes(app.coneMosaicTab);
            xlabel(app.coneMosaicView, 'X')
            zlabel(app.coneMosaicView, 'Z')
            app.mRGCmosaicTab = uitab(app.TabGroup);
            app.mRGCmosaicTab.Title = 'mRGC mosaic';
            app.pRGCmosaicTab = uitab(app.TabGroup);
            app.pRGCmosaicTab.Title = 'pRGC mosaic';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.visualFieldTab = uitab(app.TabGroup2);
            app.visualFieldTab.Title = 'visual field';
            app.visualFieldEyeSwitch = uiswitch(app.visualFieldTab, 'slider');
            app.visualFieldEyeSwitch.Items = {'left eye', 'right eye'};
            app.visualFieldEyeSwitch.ValueChangedFcn = createCallbackFcn(app, @visualFieldEyeSwitchValueChanged, true);
            app.visualFieldEyeSwitch.Value = 'right eye';
            app.radialeccentricitydegsLabel = uilabel(app.visualFieldTab);
            app.radialeccentricitydegsLabel.Text = {'radial eccentricity (degs) '; ''};
            app.visualFieldRadialEccentricitySlider = uislider(app.visualFieldTab);
            app.visualFieldRadialEccentricitySlider.ValueChangedFcn = createCallbackFcn(app, @visualFieldRadialEccentricitySliderValueChanged, true);
            app.visualFieldRadialEccentricitySlider.ValueChangingFcn = createCallbackFcn(app, @visualFieldRadialEccentricitySliderValueChanging, true);
            app.polareccentricitydegsLabel = uilabel(app.visualFieldTab);
            app.polareccentricitydegsLabel.Text = 'polar eccentricity (degs) ';
            app.visualFieldPolarEccentricityKnob = uiknob(app.visualFieldTab, 'continuous');
            app.visualFieldPolarEccentricityKnob.Limits = [0 360];
            app.visualFieldPolarEccentricityKnob.ValueChangedFcn = createCallbackFcn(app, @visualFieldPolarEccentricityKnobValueChanged, true);
            app.visualFieldPolarEccentricityKnob.ValueChangingFcn = createCallbackFcn(app, @visualFieldPolarEccentricityKnobValueChanging, true);
            app.fieldofviewdegsLabel = uilabel(app.visualFieldTab);
            app.fieldofviewdegsLabel.Text = 'field of view (degs) ';
            app.visualFieldFieldOfViewSlider = uislider(app.visualFieldTab);
            app.visualFieldFieldOfViewSlider.ValueChangedFcn = createCallbackFcn(app, @visualFieldFieldOfViewSliderValueChanged, true);
            app.visualFieldFieldOfViewSlider.ValueChangingFcn = createCallbackFcn(app, @visualFieldFieldOfViewSliderValueChanging, true);
            app.magnificationfactorumdegEditFieldLabel = uilabel(app.visualFieldTab);
            app.magnificationfactorumdegEditFieldLabel.Text = 'magnification factor (um/deg)';
            app.visualFieldMagnificationFactorEditField = uieditfield(app.visualFieldTab, 'numeric');
            app.visualFieldView = uiaxes(app.visualFieldTab);
            xlabel(app.visualFieldView, 'X')
            zlabel(app.visualFieldView, 'Z')
            app.stimulusTab = uitab(app.TabGroup2);
            app.stimulusTab.Title = 'stimulus';
            app.performanceassessmentTab = uitab(app.TabGroup2);
            app.performanceassessmentTab.Title = 'performance assessment';
            app.Panel = uipanel(app.GridLayout);
            app.leftStatusField = uieditfield(app.Panel, 'text');
            app.leftStatusField.Editable = 'off';
            app.leftStatusField.Value = 'left status';
            app.Panel_2 = uipanel(app.GridLayout);
            app.rightStatusField = uieditfield(app.Panel_2, 'text');
            app.rightStatusField.Editable = 'off';
            app.rightStatusField.Value = 'right status';
            % Show the figure after all components are created
            app.mainView.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISETBioCSFGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainView)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainView)
        end
    end
end